classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Label_2               matlab.ui.control.Label
        yourstatusLabel       matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        yourBMILabel          matlab.ui.control.Label
        Button                matlab.ui.control.Button
        unitweight            matlab.ui.control.DropDown
        unitsDropDown_2Label  matlab.ui.control.Label
        unitheight            matlab.ui.control.DropDown
        unitsDropDownLabel    matlab.ui.control.Label
        WeightEditField       matlab.ui.control.NumericEditField
        WeightEditFieldLabel  matlab.ui.control.Label
        heightEditField       matlab.ui.control.NumericEditField
        heightEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            height = app.heightEditField.Value;
            heightunit = app.unitheight.Value;
            weight = app.WeightEditField.Value;
            weightunit = app.unitweight.Value;

            switch heightunit
                case 'm'
                    height_m = height;
                case 'cm'
                    height_m = height/100;
                case 'in'
                    height_m = height*0.0254; % in = 0.0254m
            end

            switch weightunit
                case 'lb'
                    weight_kg = weight/2.20462;
                case 'kg'
                    weight_kg = weight;
            end

            BMI = weight_kg/height_m^2;
            
            if BMI < 18.5
                status = 'Underweight';
            elseif BMI >= 18.5 && BMI < 25
                status = 'Normal';
            elseif BMI >= 25 && BMI < 30 
                status = 'Overweight';
            else 
                status = 'Obese';
            end
            app.Label.Text = sprintf('%2f',BMI);
            app.Label_2.Text = status;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.heightEditFieldLabel = uilabel(app.UIFigure);
            app.heightEditFieldLabel.Text = 'height';
            app.heightEditField = uieditfield(app.UIFigure, 'numeric');
            app.heightEditField.Limits = [0 Inf];
            app.WeightEditFieldLabel = uilabel(app.UIFigure);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.WeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.unitsDropDownLabel = uilabel(app.UIFigure);
            app.unitsDropDownLabel.Text = 'units';
            app.unitheight = uidropdown(app.UIFigure);
            app.unitheight.Items = {'m', 'cm', 'in'};
            app.unitheight.Value = 'm';
            app.unitsDropDown_2Label = uilabel(app.UIFigure);
            app.unitsDropDown_2Label.Text = 'units';
            app.unitweight = uidropdown(app.UIFigure);
            app.unitweight.Items = {'kg', 'lb'};
            app.unitweight.Value = 'kg';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.yourBMILabel = uilabel(app.UIFigure);
            app.yourBMILabel.Text = 'your BMI:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '.............';
            app.yourstatusLabel = uilabel(app.UIFigure);
            app.yourstatusLabel.Text = 'your status:';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '........................';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end