classdef caculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Button_plus_5  matlab.ui.control.Button
        Button_plus_4  matlab.ui.control.Button
        Button_plus_3  matlab.ui.control.Button
        Button_plus_2  matlab.ui.control.Button
        Button_eq      matlab.ui.control.Button
        Button_plus    matlab.ui.control.Button
        Button_minus   matlab.ui.control.Button
        Button_times   matlab.ui.control.Button
        Button_divide  matlab.ui.control.Button
        EditField      matlab.ui.control.EditField
        Button0        matlab.ui.control.Button
        Button_dot     matlab.ui.control.Button
        Button_C       matlab.ui.control.Button
        Button7        matlab.ui.control.Button
        Button4        matlab.ui.control.Button
        Button8        matlab.ui.control.Button
        Button5        matlab.ui.control.Button
        Button9        matlab.ui.control.Button
        Button6        matlab.ui.control.Button
        Button3        matlab.ui.control.Button
        Button2        matlab.ui.control.Button
        Button1        matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function appendToDisplay(app,character)
            currentText = app.EditField.Value;
            if strcmp(currentText, '0')
                newText = character;
            else 
                newText = strcat(currentText,character);
            end
            app.EditField.Value = newText;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            currentText = app.EditField.Value;
            if strcmp(currentText, '0')
                app.EditField.Value = '1';
            else 
                app.EditField.Value = strcat(currentText, '1');
            end
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            appendToDisplay(app,'2')
        end

        % Button pushed function: Button3
        function Button3Pushed(app, event)
            appendToDisplay(app,'3')
        end

        % Button pushed function: Button4
        function Button4Pushed(app, event)
            appendToDisplay(app,'4')
        end

        % Button pushed function: Button5
        function Button5Pushed(app, event)
            appendToDisplay(app,'5')
        end

        % Button pushed function: Button6
        function Button6Pushed(app, event)
            appendToDisplay(app,'6')
        end

        % Button pushed function: Button7
        function Button7Pushed(app, event)
            appendToDisplay(app,'7')
        end

        % Button pushed function: Button8
        function Button8Pushed(app, event)
            appendToDisplay(app,'8')
        end

        % Button pushed function: Button9
        function Button9Pushed(app, event)
            appendToDisplay(app,'9')
        end

        % Button pushed function: Button0
        function Button0Pushed(app, event)
            appendToDisplay(app,'0')
        end

        % Button pushed function: Button_plus
        function Button_plusPushed(app, event)
            appendToDisplay(app,'+')
        end

        % Button pushed function: Button_minus
        function Button_minusPushed(app, event)
            appendToDisplay(app,'-')
        end

        % Button pushed function: Button_times
        function Button_timesPushed(app, event)
            appendToDisplay(app,'*')
        end

        % Button pushed function: Button_divide
        function Button_dividePushed(app, event)
            appendToDisplay(app,'/')
        end

        % Button pushed function: Button_eq
        function Button_eqPushed(app, event)
            expr = app.EditField.Value;
            try
                result = eval(expr);
                app.EditField.Value = num2str(result);
            catch
                app.EditField.Value = 'Error';
            end
        end

        % Button pushed function: Button_dot
        function Button_dotPushed(app, event)
            currentText = app.EditField.Value;
            if ~contains(currentText,'.')
                app.EditField.Value = strcat(currentText,'.');
            end
        end

        % Button pushed function: Button_C
        function Button_CPushed(app, event)
            app.EditField.Value = '0';
        end

        % Button pushed function: Button_plus_2
        function Button_plus_2Pushed(app, event)

            x = str2double(app.EditField.Value);
            try
                app.EditField.Value = num2str(sind(x));
            catch
                app.EditField.Value = 'Error';
            end
        end

        % Button pushed function: Button_plus_3
        function Button_plus_3Pushed(app, event)
            x = str2double(app.EditField.Value);
            try
                app.EditField.Value = num2str(cosd(x));
            catch
                app.EditField.Value = 'Error';
            end
        end

        % Button pushed function: Button_plus_4
        function Button_plus_4Pushed(app, event)
            x = str2double(app.EditField.Value);
            try
                app.EditField.Value = num2str(tand(x));
            catch
                app.EditField.Value = 'Error';
            end
        end

        % Button pushed function: Button_plus_5
        function Button_plus_5Pushed(app, event)
            x = str2double(app.EditField.Value);
            try
                app.EditField.Value = num2str(sqrt(x));
            catch
                app.EditField.Value = 'Error';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button1 = uibutton(app.UIFigure, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Text = '1';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = '2';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button3.Text = '3';
            app.Button6 = uibutton(app.UIFigure, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @Button6Pushed, true);
            app.Button6.Text = '6';
            app.Button9 = uibutton(app.UIFigure, 'push');
            app.Button9.ButtonPushedFcn = createCallbackFcn(app, @Button9Pushed, true);
            app.Button9.Text = '9';
            app.Button5 = uibutton(app.UIFigure, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @Button5Pushed, true);
            app.Button5.Text = '5';
            app.Button8 = uibutton(app.UIFigure, 'push');
            app.Button8.ButtonPushedFcn = createCallbackFcn(app, @Button8Pushed, true);
            app.Button8.Text = '8';
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4Pushed, true);
            app.Button4.Text = '4';
            app.Button7 = uibutton(app.UIFigure, 'push');
            app.Button7.ButtonPushedFcn = createCallbackFcn(app, @Button7Pushed, true);
            app.Button7.Text = '7';
            app.Button_C = uibutton(app.UIFigure, 'push');
            app.Button_C.ButtonPushedFcn = createCallbackFcn(app, @Button_CPushed, true);
            app.Button_C.Text = 'c';
            app.Button_dot = uibutton(app.UIFigure, 'push');
            app.Button_dot.ButtonPushedFcn = createCallbackFcn(app, @Button_dotPushed, true);
            app.Button_dot.Text = '.';
            app.Button0 = uibutton(app.UIFigure, 'push');
            app.Button0.ButtonPushedFcn = createCallbackFcn(app, @Button0Pushed, true);
            app.Button0.Text = '0';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = '0';
            app.Button_divide = uibutton(app.UIFigure, 'push');
            app.Button_divide.ButtonPushedFcn = createCallbackFcn(app, @Button_dividePushed, true);
            app.Button_divide.Text = '/';
            app.Button_times = uibutton(app.UIFigure, 'push');
            app.Button_times.ButtonPushedFcn = createCallbackFcn(app, @Button_timesPushed, true);
            app.Button_times.Text = '*';
            app.Button_minus = uibutton(app.UIFigure, 'push');
            app.Button_minus.ButtonPushedFcn = createCallbackFcn(app, @Button_minusPushed, true);
            app.Button_minus.Text = '-';
            app.Button_plus = uibutton(app.UIFigure, 'push');
            app.Button_plus.ButtonPushedFcn = createCallbackFcn(app, @Button_plusPushed, true);
            app.Button_plus.Text = '+';
            app.Button_eq = uibutton(app.UIFigure, 'push');
            app.Button_eq.ButtonPushedFcn = createCallbackFcn(app, @Button_eqPushed, true);
            app.Button_eq.Text = '=';
            app.Button_plus_2 = uibutton(app.UIFigure, 'push');
            app.Button_plus_2.ButtonPushedFcn = createCallbackFcn(app, @Button_plus_2Pushed, true);
            app.Button_plus_2.Text = 'sin';
            app.Button_plus_3 = uibutton(app.UIFigure, 'push');
            app.Button_plus_3.ButtonPushedFcn = createCallbackFcn(app, @Button_plus_3Pushed, true);
            app.Button_plus_3.Text = 'cos';
            app.Button_plus_4 = uibutton(app.UIFigure, 'push');
            app.Button_plus_4.ButtonPushedFcn = createCallbackFcn(app, @Button_plus_4Pushed, true);
            app.Button_plus_4.Text = 'tan';
            app.Button_plus_5 = uibutton(app.UIFigure, 'push');
            app.Button_plus_5.ButtonPushedFcn = createCallbackFcn(app, @Button_plus_5Pushed, true);
            app.Button_plus_5.Text = 'sqr';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = caculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end